classdef appdenemeson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        RPMEditField                matlab.ui.control.NumericEditField
        RPMEditFieldLabel           matlab.ui.control.Label
        BPMEditField                matlab.ui.control.NumericEditField
        BPMEditFieldLabel           matlab.ui.control.Label
        HighPassCutOffSpinner       matlab.ui.control.Spinner
        HighPassCutOffSpinnerLabel  matlab.ui.control.Label
        LowPassCutOffSpinner        matlab.ui.control.Spinner
        LowPassCutOffSpinnerLabel   matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        OrderSpinner                matlab.ui.control.Spinner
        OrderSpinnerLabel           matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SerialObj % Seri port nesnesi
        TimerObj % Timer nesnesi
        IsPlotting = false; % Grafik çizim durumu
        PlotLine % Çizgi nesnesi
        DataBuffer = []; % Veri tamponu
        BufferSize = 150; % Tampon boyutu
        SamplingRate = 4000; % Örnekleme frekansı (Hz)
        FilteredLine % Filtrelenmiş veri çizgi nesnesi
        FFTLine
        FilteredFFTLine % Filtrelenmiş FFT çizgi nesnesi
    end

    methods (Access = private)


        function updatePlot(app)
            % Zaman penceresi (kaydırma aralığı)

            % Seri porttan veri alımı
            if app.IsPlotting && app.SerialObj.NumBytesAvailable > 0
                data = readline(app.SerialObj); % Arduino'dan bir satır oku
                splitData = str2double(strsplit(data, ',')); % Veriyi ayır

                % Analog değer ve zaman bilgisi
                analogValue = (splitData(1)/1023)*5;
                elapsedMilliseconds = splitData(2); % Zamanı saniyeye çevir

                % Çizgi nesnesi oluşturma veya mevcut olanı kullanma
                if isempty(app.PlotLine)
                    app.PlotLine = animatedline(app.UIAxes, 'MaximumNumPoints', 300);
                end

                addpoints(app.PlotLine, elapsedMilliseconds/1000, analogValue); % Mili saniye/1000=saniye

                % animatedline'dan değerleri alma
                [t, y] = getpoints(app.PlotLine);

                % Y eksenini sınırlama
                ylim(app.UIAxes, [min(y)-2, max(y)+2]);

                % X eksenini sınırlama
                if length(t) > 1
                    xlim(app.UIAxes, [t(end)-5, t(end)]);
                end

                % Veriyi tamponda saklama
                app.DataBuffer = [app.DataBuffer, analogValue];

                % Tampon boyutunu kontrol etme ve eski verileri çıkarma
                if length(app.DataBuffer) > app.BufferSize
                    app.DataBuffer = app.DataBuffer(end-app.BufferSize+1:end);
                end

                % BPM hesaplama
                if ~isempty(app.DataBuffer)
                    windowTime = 10; % 10 saniyelik pencere
                    recentData = app.DataBuffer(max(1, end - app.SamplingRate*windowTime + 1):end); % Son 10 saniyelik veriyi al
                    peaks = find(recentData > 1.8); % 1.5 üzerindeki değerleri bul
                    peakCount = numel(peaks); % Zirve sayısını hesapla
                    bpm = (peakCount / windowTime) * 60; % BPM hesapla

                    % BPM değerini arayüzde göster
                    app.BPMEditField.Value = round(bpm); % BPM değerini yuvarlayarak güncelle
                end
                % RPM hesaplama
                if ~isempty(app.DataBuffer)
                    windowTime1 = 3; % 3 saniyelik pencere
                    recentData = app.DataBuffer(max(1, end - app.SamplingRate*windowTime1 + 1):end); % Son 3 saniyelik veriyi al
                    peaks = find(recentData > 0.9, 1); % 1.5 üzerindeki değerleri bul

                    % Eğer en az bir zirve varsa, bunu 1 nefes olarak kabul et
                    if ~isempty(peaks)
                        breaths = 1; % 3 saniyede 1 nefes
                    else
                        breaths = 0; % Zirve yoksa nefes sayılmaz
                    end

                    rpm = breaths * 20; % 3 saniyedeki nefes sayısını 20 ile çarp
                    app.RPMEditField.Value = rpm; % RPM değerini güncelle
                end

                % Tampon dolduğunda FFT hesaplama ve UIAxes2'de gösterme
                if length(app.DataBuffer) == app.BufferSize
                    N = length(app.DataBuffer); % Veri uzunluğu
                    f = (0:app.BufferSize-1)*(4800/app.BufferSize); % Frekans ekseni

                    % FFT hesaplama (built-in fonksiyon)
                    Y = fft(app.DataBuffer);

                    P = abs(Y); % Genlik spektrumu

                    % FFT grafiğini güncelleme veya oluşturma
                    if isempty(app.FFTLine)
                        app.FFTLine = plot(app.UIAxes2, f, P);
                    else
                        set(app.FFTLine, 'YData', P);
                        set(app.FFTLine, 'XData', f);
                    end

                    % FFT eksenlerini sınırlama
                    xlim(app.UIAxes2, [0, 2500]); % X eksenini 500 Hz'e kadar sınırlama
                    ylim(app.UIAxes2, [0, 250]); % X eksenini 500 Hz'e kadar sınırlama

                end

                % Band-pass filtresi uygulama
                if length(app.DataBuffer) >= app.BufferSize
                    % Spinnerlardan gelen değerleri al
                    filterOrder = app.OrderSpinner.Value;
                    lowCutoffFreq = app.LowPassCutOffSpinner.Value;
                    highCutoffFreq = app.HighPassCutOffSpinner.Value;

                    % Filtre parametreleri
                    normalizedLowCutoff = lowCutoffFreq / (app.SamplingRate / 2); % Normalleştirilmiş alt kesme frekansı
                    normalizedHighCutoff = highCutoffFreq / (app.SamplingRate / 2); % Normalleştirilmiş üst kesme frekansı
                    [b, a] = butter(filterOrder, [normalizedLowCutoff, normalizedHighCutoff], 'bandpass'); % Band-pass filtresi

                    % Filtrelenmiş veriyi hesaplama
                    filteredData = filter(b, a, app.DataBuffer);

                    % Filtrelenmiş veriyi UIAxes3'te gösterme
                    if isempty(app.FilteredLine)
                        app.FilteredLine = animatedline(app.UIAxes3, 'MaximumNumPoints', 300);
                    end

                    addpoints(app.FilteredLine, elapsedMilliseconds/1000, filteredData(end)); % Mili saniye/1000=saniye

                    % animatedline'dan değerleri alma
                    [tf, yf] = getpoints(app.FilteredLine);

                    % Y eksenini sınırlama
                    ylim(app.UIAxes3, [min(yf)-2, max(yf)+2]);

                    % X eksenini sınırlama
                    if length(tf) > 1
                        xlim(app.UIAxes3, [tf(end)-5, tf(end)]);
                    end

                    % Filtrelenmiş verinin FFT'sini hesaplama ve UIAxes4'te gösterme
                    if length(filteredData) == app.BufferSize
                        Yf = fft(filteredData);
                        Pf = abs(Yf); % Genlik spektrumu

                        % FFT grafiğini güncelleme veya oluşturma
                        if isempty(app.FilteredFFTLine)
                            app.FilteredFFTLine = plot(app.UIAxes4, f, Pf);
                        else
                            set(app.FilteredFFTLine, 'YData', Pf);
                            set(app.FilteredFFTLine, 'XData', f);
                        end

                        % FFT eksenlerini sınırlama
                        xlim(app.UIAxes4, [0, 2500]); % X eksenini 500 Hz'e kadar sınırlama
                        ylim(app.UIAxes4, [0, 150]); % X eksenini 500 Hz'e kadar sınırlama

                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Başlangıçta seri portu ve timer nesnesini başlatma
            app.SerialObj = [];
            app.TimerObj = [];
            app.FFTLine = []; % FFT çizgi nesnesini başlatma
            app.FilteredLine = []; % Filtrelenmiş veri çizgi nesnesini başlatma
            app.FilteredFFTLine = []; % Filtrelenmiş FFT çizgi nesnesini başlatma
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Seri portu başlatın (Arduino'nun bağlı olduğu COM portunu kontrol edin)
            if isempty(app.SerialObj)
                app.SerialObj = serialport("COM8", 115200); % Arduino'nun bağlı olduğu COM port
            end

            % Timer nesnesini oluştur
            if isempty(app.TimerObj)
                app.TimerObj = timer('ExecutionMode', 'fixedRate', ...
                    'Period', 0.001, ...
                    'TimerFcn', @(~,~) app.updatePlot()); % 100 ms güncelleme
            end

            % Timer'ı başlat
            start(app.TimerObj);
            app.IsPlotting = true;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Zamanlayıcıyı durdur
            if ~isempty(app.TimerObj)
                stop(app.TimerObj);
            end

            % Grafik çizimini durdur
            app.IsPlotting = false;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Timer ve seri bağlantıyı temizleme
            if ~isempty(app.TimerObj)
                stop(app.TimerObj);
                delete(app.TimerObj);
            end

            if ~isempty(app.SerialObj)
                clear app.SerialObj;
            end

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Raw Signal')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Voltage (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Raw Signals FFT')
            xlabel(app.UIAxes2, 'Frequence (Hz)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Filtered Signals FFT')
            xlabel(app.UIAxes4, 'Frequence (Hz)')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Filtered Signal')
            xlabel(app.UIAxes3, 'Time (sec)')
            ylabel(app.UIAxes3, 'Voltage (V)')
            zlabel(app.UIAxes3, 'Z')
            app.OrderSpinnerLabel = uilabel(app.GridLayout);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.GridLayout);
            app.OrderSpinner.Limits = [1 5];
            app.OrderSpinner.Value = 1;
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LowPassCutOffSpinnerLabel = uilabel(app.GridLayout);
            app.LowPassCutOffSpinnerLabel.Text = 'Low Pass Cut Off';
            app.LowPassCutOffSpinner = uispinner(app.GridLayout);
            app.LowPassCutOffSpinner.Step = 10;
            app.LowPassCutOffSpinner.Limits = [10 500];
            app.LowPassCutOffSpinner.Value = 10;
            app.HighPassCutOffSpinnerLabel = uilabel(app.GridLayout);
            app.HighPassCutOffSpinnerLabel.Text = 'High Pass Cut Off';
            app.HighPassCutOffSpinner = uispinner(app.GridLayout);
            app.HighPassCutOffSpinner.Step = 100;
            app.HighPassCutOffSpinner.Limits = [200 800];
            app.HighPassCutOffSpinner.Value = 200;
            app.BPMEditFieldLabel = uilabel(app.GridLayout);
            app.BPMEditFieldLabel.Text = 'BPM';
            app.BPMEditField = uieditfield(app.GridLayout, 'numeric');
            app.RPMEditFieldLabel = uilabel(app.GridLayout);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.RPMEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appdenemeson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end